classdef zeffiro_interface_segmentation_tool_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        h_zeffiro_window_main           matlab.ui.Figure
        h_menu_project                  matlab.ui.container.Menu
        h_menu_new                      matlab.ui.container.Menu
        h_menu_open                     matlab.ui.container.Menu
        h_menu_save                     matlab.ui.container.Menu
        h_menu_save_as                  matlab.ui.container.Menu
        h_menu_save_figures_as          matlab.ui.container.Menu
        h_menu_print_to_file            matlab.ui.container.Menu
        h_menu_exit                     matlab.ui.container.Menu
        h_menu_export                   matlab.ui.container.Menu
        h_menu_export_volume_data       matlab.ui.container.Menu
        h_menu_segmentation_data        matlab.ui.container.Menu
        h_menu_lead_field               matlab.ui.container.Menu
        h_menu_source_positions         matlab.ui.container.Menu
        h_menu_source_directions        matlab.ui.container.Menu
        h_menu_reconstruction           matlab.ui.container.Menu
        h_menu_import                   matlab.ui.container.Menu
        h_menu_new_segmentation_from_folder  matlab.ui.container.Menu
        h_menu_import_segmentation_update_from_folder  matlab.ui.container.Menu
        h_menu_import_new_project_from_folder  matlab.ui.container.Menu
        h_menu_import_project_update_from_folder  matlab.ui.container.Menu
        h_menu_import_volume_data       matlab.ui.container.Menu
        h_menu_import_measurement_data  matlab.ui.container.Menu
        h_menu_import_noise_data        matlab.ui.container.Menu
        h_menu_import_reconstruction    matlab.ui.container.Menu
        h_menu_import_current_pattern   matlab.ui.container.Menu
        h_menu_edit                     matlab.ui.container.Menu
        h_menu_reset_lead_field         matlab.ui.container.Menu
        h_menu_reset_volume_data        matlab.ui.container.Menu
        h_menu_reset_measurement_data   matlab.ui.container.Menu
        h_menu_reset_reconstruction     matlab.ui.container.Menu
        h_menu_merge_lead_field         matlab.ui.container.Menu
        h_menu_inverse_tools            matlab.ui.container.Menu
        h_menu_ias_map_estimation       matlab.ui.container.Menu
        h_menu_ias_map_estimation_roi   matlab.ui.container.Menu
        h_menu_ias_map_multiresolution  matlab.ui.container.Menu
        h_menu_forward_tools            matlab.ui.container.Menu
        h_menu_butterfly_plot           matlab.ui.container.Menu
        h_menu_find_synthetic_source    matlab.ui.container.Menu
        h_menu_generate_eit_data        matlab.ui.container.Menu
        h_menu_multi_tools              matlab.ui.container.Menu
        h_menu_mesh_tool                matlab.ui.container.Menu
        h_menu_figure_tool              matlab.ui.container.Menu
        h_menu_parcellation_tool        matlab.ui.container.Menu
        h_menu_options                  matlab.ui.container.Menu
        h_menu_window                   matlab.ui.container.Menu
        h_menu_close_tools              matlab.ui.container.Menu
        h_menu_close_figures            matlab.ui.container.Menu
        h_menu_help                     matlab.ui.container.Menu
        h_menu_documentation            matlab.ui.container.Menu
        h_menu_about                    matlab.ui.container.Menu
        h_w_name                        matlab.ui.control.Label
        h_w_on                          matlab.ui.control.CheckBox
        h_pushbutton3                   matlab.ui.control.Button
        h_g_name                        matlab.ui.control.Label
        h_pushbutton4                   matlab.ui.control.Button
        h_pushbutton5                   matlab.ui.control.Button
        h_c_name                        matlab.ui.control.Label
        h_pushbutton6                   matlab.ui.control.Button
        h_pushbutton7                   matlab.ui.control.Button
        h_sk_name                       matlab.ui.control.Label
        h_pushbutton8                   matlab.ui.control.Button
        h_pushbutton9                   matlab.ui.control.Button
        h_sc_name                       matlab.ui.control.Label
        h_pushbutton10                  matlab.ui.control.Button
        h_g_on                          matlab.ui.control.CheckBox
        h_c_on                          matlab.ui.control.CheckBox
        h_sk_on                         matlab.ui.control.CheckBox
        h_sc_on                         matlab.ui.control.CheckBox
        h_s_name                        matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        text14                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text32                          matlab.ui.control.Label
        text33                          matlab.ui.control.Label
        h_edit3                         matlab.ui.control.EditField
        h_edit6                         matlab.ui.control.EditField
        h_edit7                         matlab.ui.control.EditField
        h_edit12                        matlab.ui.control.EditField
        h_edit13                        matlab.ui.control.EditField
        h_edit14                        matlab.ui.control.EditField
        h_edit15                        matlab.ui.control.EditField
        text36                          matlab.ui.control.Label
        text61                          matlab.ui.control.Label
        h_edit29                        matlab.ui.control.EditField
        h_edit36                        matlab.ui.control.EditField
        h_edit50                        matlab.ui.control.EditField
        h_edit57                        matlab.ui.control.EditField
        h_edit64                        matlab.ui.control.EditField
        h_w_visible                     matlab.ui.control.CheckBox
        h_g_visible                     matlab.ui.control.CheckBox
        h_c_visible                     matlab.ui.control.CheckBox
        h_sk_visible                    matlab.ui.control.CheckBox
        h_sc_visible                    matlab.ui.control.CheckBox
        h_pushbutton1                   matlab.ui.control.Button
        h_pushbutton2                   matlab.ui.control.Button
        h_pushbutton16                  matlab.ui.control.Button
        h_pushbutton17                  matlab.ui.control.Button
        h_s_visible                     matlab.ui.control.CheckBox
        h_edit70                        matlab.ui.control.EditField
        h_edit71                        matlab.ui.control.EditField
        h_edit72                        matlab.ui.control.EditField
        h_edit73                        matlab.ui.control.EditField
        h_edit74                        matlab.ui.control.EditField
        h_s_on                          matlab.ui.control.CheckBox
        text162                         matlab.ui.control.Label
        text163                         matlab.ui.control.Label
        text164                         matlab.ui.control.Label
        text168                         matlab.ui.control.Label
        text169                         matlab.ui.control.Label
        text185                         matlab.ui.control.Label
        text186                         matlab.ui.control.Label
        text187                         matlab.ui.control.Label
        text188                         matlab.ui.control.Label
        text189                         matlab.ui.control.Label
        text190                         matlab.ui.control.Label
        text191                         matlab.ui.control.Label
        text192                         matlab.ui.control.Label
        text193                         matlab.ui.control.Label
        text194                         matlab.ui.control.Label
        h_pushbutton101                 matlab.ui.control.Button
        h_d1_name                       matlab.ui.control.EditField
        h_pushbutton102                 matlab.ui.control.Button
        h_pushbutton201                 matlab.ui.control.Button
        h_d2_name                       matlab.ui.control.EditField
        h_pushbutton202                 matlab.ui.control.Button
        h_d1_on                         matlab.ui.control.CheckBox
        h_d2_on                         matlab.ui.control.CheckBox
        h_edit129                       matlab.ui.control.EditField
        h_edit229                       matlab.ui.control.EditField
        h_d1_visible                    matlab.ui.control.CheckBox
        h_d2_visible                    matlab.ui.control.CheckBox
        h_edit170                       matlab.ui.control.EditField
        h_edit270                       matlab.ui.control.EditField
        text237                         matlab.ui.control.Label
        text238                         matlab.ui.control.Label
        text239                         matlab.ui.control.Label
        text240                         matlab.ui.control.Label
        h_pushbutton301                 matlab.ui.control.Button
        h_d3_name                       matlab.ui.control.EditField
        h_pushbutton302                 matlab.ui.control.Button
        h_d3_on                         matlab.ui.control.CheckBox
        h_edit329                       matlab.ui.control.EditField
        h_d3_visible                    matlab.ui.control.CheckBox
        h_edit370                       matlab.ui.control.EditField
        text256                         matlab.ui.control.Label
        text257                         matlab.ui.control.Label
        h_edit9001                      matlab.ui.control.EditField
        h_edit9002                      matlab.ui.control.EditField
        h_edit9003                      matlab.ui.control.EditField
        h_edit9004                      matlab.ui.control.EditField
        h_edit9005                      matlab.ui.control.EditField
        h_edit9006                      matlab.ui.control.EditField
        h_edit9007                      matlab.ui.control.EditField
        h_edit9008                      matlab.ui.control.EditField
        text260                         matlab.ui.control.Label
        text261                         matlab.ui.control.Label
        text262                         matlab.ui.control.Label
        text263                         matlab.ui.control.Label
        text264                         matlab.ui.control.Label
        text265                         matlab.ui.control.Label
        text266                         matlab.ui.control.Label
        text267                         matlab.ui.control.Label
        h_pushbutton401                 matlab.ui.control.Button
        h_d4_name                       matlab.ui.control.EditField
        h_pushbutton402                 matlab.ui.control.Button
        h_d4_on                         matlab.ui.control.CheckBox
        h_edit429                       matlab.ui.control.EditField
        h_d4_visible                    matlab.ui.control.CheckBox
        h_edit470                       matlab.ui.control.EditField
        text305                         matlab.ui.control.Label
        text306                         matlab.ui.control.Label
        h_edit9009                      matlab.ui.control.EditField
        text307                         matlab.ui.control.Label
        h_w_sources                     matlab.ui.control.DropDown
        h_d1_sources                    matlab.ui.control.DropDown
        h_d2_sources                    matlab.ui.control.DropDown
        h_d3_sources                    matlab.ui.control.DropDown
        h_d4_sources                    matlab.ui.control.DropDown
        h_g_sources                     matlab.ui.control.DropDown
        h_c_sources                     matlab.ui.control.DropDown
        h_sk_sources                    matlab.ui.control.DropDown
        h_sc_sources                    matlab.ui.control.DropDown
        h_w_merge                       matlab.ui.control.CheckBox
        h_g_merge                       matlab.ui.control.CheckBox
        h_c_merge                       matlab.ui.control.CheckBox
        h_sk_merge                      matlab.ui.control.CheckBox
        h_sc_merge                      matlab.ui.control.CheckBox
        h_w_invert                      matlab.ui.control.CheckBox
        h_g_invert                      matlab.ui.control.CheckBox
        h_c_invert                      matlab.ui.control.CheckBox
        h_sk_invert                     matlab.ui.control.CheckBox
        h_sc_invert                     matlab.ui.control.CheckBox
        h_d1_merge                      matlab.ui.control.CheckBox
        h_d2_merge                      matlab.ui.control.CheckBox
        h_d1_invert                     matlab.ui.control.CheckBox
        h_d2_invert                     matlab.ui.control.CheckBox
        h_d3_merge                      matlab.ui.control.CheckBox
        h_d3_invert                     matlab.ui.control.CheckBox
        h_d4_merge                      matlab.ui.control.CheckBox
        h_d4_invert                     matlab.ui.control.CheckBox
        h_d5_name                       matlab.ui.control.EditField
        h_d5_on                         matlab.ui.control.CheckBox
        h_d6_button_1                   matlab.ui.control.Button
        h_d6_name                       matlab.ui.control.EditField
        h_d6_button_2                   matlab.ui.control.Button
        h_d7_button_1                   matlab.ui.control.Button
        h_d7_name                       matlab.ui.control.EditField
        h_d7_button_2                   matlab.ui.control.Button
        h_d8_button_1                   matlab.ui.control.Button
        h_d8_name                       matlab.ui.control.EditField
        h_d8_button_2                   matlab.ui.control.Button
        h_d9_button_1                   matlab.ui.control.Button
        h_d9_name                       matlab.ui.control.EditField
        h_d9_button_2                   matlab.ui.control.Button
        h_d6_on                         matlab.ui.control.CheckBox
        h_d7_on                         matlab.ui.control.CheckBox
        h_d8_on                         matlab.ui.control.CheckBox
        h_d9_on                         matlab.ui.control.CheckBox
        text324                         matlab.ui.control.Label
        h_d5_scaling                    matlab.ui.control.EditField
        h_d6_scaling                    matlab.ui.control.EditField
        h_d7_scaling                    matlab.ui.control.EditField
        h_d8_scaling                    matlab.ui.control.EditField
        h_d9_scaling                    matlab.ui.control.EditField
        h_d5_visible                    matlab.ui.control.CheckBox
        h_d6_visible                    matlab.ui.control.CheckBox
        h_d7_visible                    matlab.ui.control.CheckBox
        h_d8_visible                    matlab.ui.control.CheckBox
        h_d9_visible                    matlab.ui.control.CheckBox
        h_d5_button_1                   matlab.ui.control.Button
        h_d5_button_2                   matlab.ui.control.Button
        h_d5_sigma                      matlab.ui.control.EditField
        h_d6_sigma                      matlab.ui.control.EditField
        h_d7_sigma                      matlab.ui.control.EditField
        h_d8_sigma                      matlab.ui.control.EditField
        h_d9_sigma                      matlab.ui.control.EditField
        text325                         matlab.ui.control.Label
        text326                         matlab.ui.control.Label
        text327                         matlab.ui.control.Label
        text328                         matlab.ui.control.Label
        text329                         matlab.ui.control.Label
        text330                         matlab.ui.control.Label
        text331                         matlab.ui.control.Label
        text332                         matlab.ui.control.Label
        text333                         matlab.ui.control.Label
        h_d10_button_1                  matlab.ui.control.Button
        h_d10_name                      matlab.ui.control.EditField
        h_d10_button_2                  matlab.ui.control.Button
        h_d11_button_1                  matlab.ui.control.Button
        h_d11_name                      matlab.ui.control.EditField
        h_d11_button_2                  matlab.ui.control.Button
        h_d10_on                        matlab.ui.control.CheckBox
        h_d11_on                        matlab.ui.control.CheckBox
        h_d10_scaling                   matlab.ui.control.EditField
        h_d11_scaling                   matlab.ui.control.EditField
        h_d10_visible                   matlab.ui.control.CheckBox
        h_d11_visible                   matlab.ui.control.CheckBox
        h_d10_sigma                     matlab.ui.control.EditField
        h_d11_sigma                     matlab.ui.control.EditField
        text336                         matlab.ui.control.Label
        text337                         matlab.ui.control.Label
        text338                         matlab.ui.control.Label
        text339                         matlab.ui.control.Label
        h_d12_button_1                  matlab.ui.control.Button
        h_d12_name                      matlab.ui.control.EditField
        h_d12_button_2                  matlab.ui.control.Button
        h_d12_on                        matlab.ui.control.CheckBox
        h_d12_scaling                   matlab.ui.control.EditField
        h_d12_visible                   matlab.ui.control.CheckBox
        h_d12_sigma                     matlab.ui.control.EditField
        text341                         matlab.ui.control.Label
        text342                         matlab.ui.control.Label
        h_d5_priority                   matlab.ui.control.EditField
        h_d6_priority                   matlab.ui.control.EditField
        h_d7_priority                   matlab.ui.control.EditField
        h_d8_priority                   matlab.ui.control.EditField
        h_d9_priority                   matlab.ui.control.EditField
        h_d10_priority                  matlab.ui.control.EditField
        h_d11_priority                  matlab.ui.control.EditField
        h_d12_priority                  matlab.ui.control.EditField
        text343                         matlab.ui.control.Label
        text344                         matlab.ui.control.Label
        text345                         matlab.ui.control.Label
        text346                         matlab.ui.control.Label
        text347                         matlab.ui.control.Label
        text348                         matlab.ui.control.Label
        text349                         matlab.ui.control.Label
        text350                         matlab.ui.control.Label
        h_d13_button_1                  matlab.ui.control.Button
        h_d13_name                      matlab.ui.control.EditField
        h_d13_button_2                  matlab.ui.control.Button
        h_d13_on                        matlab.ui.control.CheckBox
        h_d13_scaling                   matlab.ui.control.EditField
        h_d13_visible                   matlab.ui.control.CheckBox
        h_d13_sigma                     matlab.ui.control.EditField
        text352                         matlab.ui.control.Label
        text353                         matlab.ui.control.Label
        h_d13_priority                  matlab.ui.control.EditField
        text354                         matlab.ui.control.Label
        h_d5_sources                    matlab.ui.control.DropDown
        h_d10_sources                   matlab.ui.control.DropDown
        h_d11_sources                   matlab.ui.control.DropDown
        h_d12_sources                   matlab.ui.control.DropDown
        h_d13_sources                   matlab.ui.control.DropDown
        h_d6_sources                    matlab.ui.control.DropDown
        h_d7_sources                    matlab.ui.control.DropDown
        h_d8_sources                    matlab.ui.control.DropDown
        h_d9_sources                    matlab.ui.control.DropDown
        h_d5_merge                      matlab.ui.control.CheckBox
        h_d6_merge                      matlab.ui.control.CheckBox
        h_d7_merge                      matlab.ui.control.CheckBox
        h_d8_merge                      matlab.ui.control.CheckBox
        h_d9_merge                      matlab.ui.control.CheckBox
        h_d5_invert                     matlab.ui.control.CheckBox
        h_d6_invert                     matlab.ui.control.CheckBox
        h_d7_invert                     matlab.ui.control.CheckBox
        h_d8_invert                     matlab.ui.control.CheckBox
        h_d9_invert                     matlab.ui.control.CheckBox
        h_d10_merge                     matlab.ui.control.CheckBox
        h_d11_merge                     matlab.ui.control.CheckBox
        h_d10_invert                    matlab.ui.control.CheckBox
        h_d11_invert                    matlab.ui.control.CheckBox
        h_d12_merge                     matlab.ui.control.CheckBox
        h_d12_invert                    matlab.ui.control.CheckBox
        h_d13_merge                     matlab.ui.control.CheckBox
        h_d13_invert                    matlab.ui.control.CheckBox
        text355                         matlab.ui.control.Label
        text356                         matlab.ui.control.Label
        text357                         matlab.ui.control.Label
        text358                         matlab.ui.control.Label
        text359                         matlab.ui.control.Label
        text360                         matlab.ui.control.Label
        text361                         matlab.ui.control.Label
        h_d14_name                      matlab.ui.control.EditField
        h_d14_on                        matlab.ui.control.CheckBox
        h_d15_button_1                  matlab.ui.control.Button
        h_d15_name                      matlab.ui.control.EditField
        h_d15_button_2                  matlab.ui.control.Button
        h_d16_button_1                  matlab.ui.control.Button
        h_d16_name                      matlab.ui.control.EditField
        h_d16_button_2                  matlab.ui.control.Button
        h_d17_button_1                  matlab.ui.control.Button
        h_d17_name                      matlab.ui.control.EditField
        h_d17_button_2                  matlab.ui.control.Button
        h_d18_button_1                  matlab.ui.control.Button
        h_d18_name                      matlab.ui.control.EditField
        h_d18_button_2                  matlab.ui.control.Button
        h_d15_on                        matlab.ui.control.CheckBox
        h_d16_on                        matlab.ui.control.CheckBox
        h_d17_on                        matlab.ui.control.CheckBox
        h_d18_on                        matlab.ui.control.CheckBox
        text389                         matlab.ui.control.Label
        h_d14_scaling                   matlab.ui.control.EditField
        h_d15_scaling                   matlab.ui.control.EditField
        h_d16_scaling                   matlab.ui.control.EditField
        h_d17_scaling                   matlab.ui.control.EditField
        h_d18_scaling                   matlab.ui.control.EditField
        h_d14_visible                   matlab.ui.control.CheckBox
        h_d15_visible                   matlab.ui.control.CheckBox
        h_d16_visible                   matlab.ui.control.CheckBox
        h_d17_visible                   matlab.ui.control.CheckBox
        h_d18_visible                   matlab.ui.control.CheckBox
        h_d14_button_1                  matlab.ui.control.Button
        h_d14_button_2                  matlab.ui.control.Button
        h_d14_sigma                     matlab.ui.control.EditField
        h_d15_sigma                     matlab.ui.control.EditField
        h_d16_sigma                     matlab.ui.control.EditField
        h_d17_sigma                     matlab.ui.control.EditField
        h_d18_sigma                     matlab.ui.control.EditField
        text390                         matlab.ui.control.Label
        text391                         matlab.ui.control.Label
        text392                         matlab.ui.control.Label
        text393                         matlab.ui.control.Label
        text394                         matlab.ui.control.Label
        text395                         matlab.ui.control.Label
        text396                         matlab.ui.control.Label
        text397                         matlab.ui.control.Label
        text398                         matlab.ui.control.Label
        h_d19_button_1                  matlab.ui.control.Button
        h_d19_name                      matlab.ui.control.EditField
        h_d19_button_2                  matlab.ui.control.Button
        h_d20_button_1                  matlab.ui.control.Button
        h_d20_name                      matlab.ui.control.EditField
        h_d20_button_2                  matlab.ui.control.Button
        h_d19_on                        matlab.ui.control.CheckBox
        h_d20_on                        matlab.ui.control.CheckBox
        h_d19_scaling                   matlab.ui.control.EditField
        h_d20_scaling                   matlab.ui.control.EditField
        h_d19_visible                   matlab.ui.control.CheckBox
        h_d20_visible                   matlab.ui.control.CheckBox
        h_d19_sigma                     matlab.ui.control.EditField
        h_d20_sigma                     matlab.ui.control.EditField
        text399                         matlab.ui.control.Label
        text400                         matlab.ui.control.Label
        text401                         matlab.ui.control.Label
        text402                         matlab.ui.control.Label
        h_d21_button_1                  matlab.ui.control.Button
        h_d21_name                      matlab.ui.control.EditField
        h_d21_button_2                  matlab.ui.control.Button
        h_d21_on                        matlab.ui.control.CheckBox
        h_d21_scaling                   matlab.ui.control.EditField
        h_d21_visible                   matlab.ui.control.CheckBox
        h_d21_sigma                     matlab.ui.control.EditField
        text403                         matlab.ui.control.Label
        text404                         matlab.ui.control.Label
        h_d14_priority                  matlab.ui.control.EditField
        h_d15_priority                  matlab.ui.control.EditField
        h_d16_priority                  matlab.ui.control.EditField
        h_d17_priority                  matlab.ui.control.EditField
        h_d18_priority                  matlab.ui.control.EditField
        h_d19_priority                  matlab.ui.control.EditField
        h_d20_priority                  matlab.ui.control.EditField
        h_d21_priority                  matlab.ui.control.EditField
        text405                         matlab.ui.control.Label
        text406                         matlab.ui.control.Label
        text407                         matlab.ui.control.Label
        text408                         matlab.ui.control.Label
        text409                         matlab.ui.control.Label
        text410                         matlab.ui.control.Label
        text411                         matlab.ui.control.Label
        text412                         matlab.ui.control.Label
        h_d22_button_1                  matlab.ui.control.Button
        h_d22_name                      matlab.ui.control.EditField
        h_d22_button_2                  matlab.ui.control.Button
        h_d22_on                        matlab.ui.control.CheckBox
        h_d22_scaling                   matlab.ui.control.EditField
        h_d22_visible                   matlab.ui.control.CheckBox
        h_d22_sigma                     matlab.ui.control.EditField
        text413                         matlab.ui.control.Label
        text414                         matlab.ui.control.Label
        h_d22_priority                  matlab.ui.control.EditField
        text415                         matlab.ui.control.Label
        h_d14_sources                   matlab.ui.control.DropDown
        h_d19_sources                   matlab.ui.control.DropDown
        h_d20_sources                   matlab.ui.control.DropDown
        h_d21_sources                   matlab.ui.control.DropDown
        h_d22_sources                   matlab.ui.control.DropDown
        h_d15_sources                   matlab.ui.control.DropDown
        h_d16_sources                   matlab.ui.control.DropDown
        h_d17_sources                   matlab.ui.control.DropDown
        h_d18_sources                   matlab.ui.control.DropDown
        h_d14_merge                     matlab.ui.control.CheckBox
        h_d15_merge                     matlab.ui.control.CheckBox
        h_d16_merge                     matlab.ui.control.CheckBox
        h_d17_merge                     matlab.ui.control.CheckBox
        h_d18_merge                     matlab.ui.control.CheckBox
        h_d14_invert                    matlab.ui.control.CheckBox
        h_d15_invert                    matlab.ui.control.CheckBox
        h_d16_invert                    matlab.ui.control.CheckBox
        h_d17_invert                    matlab.ui.control.CheckBox
        h_d18_invert                    matlab.ui.control.CheckBox
        h_d19_merge                     matlab.ui.control.CheckBox
        h_d20_merge                     matlab.ui.control.CheckBox
        h_d19_invert                    matlab.ui.control.CheckBox
        h_d20_invert                    matlab.ui.control.CheckBox
        h_d21_merge                     matlab.ui.control.CheckBox
        h_d21_invert                    matlab.ui.control.CheckBox
        h_d22_merge                     matlab.ui.control.CheckBox
        h_d22_invert                    matlab.ui.control.CheckBox
        text416                         matlab.ui.control.Label
        text417                         matlab.ui.control.Label
        h_save_file                     matlab.ui.control.Label
        h_save_file_path                matlab.ui.control.Label
        text420                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        h_project_size                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function local_CreateFcn(app, hObject, eventdata, createfcn, appdata)
            
            if ~isempty(appdata)
               names = fieldnames(appdata);
               for i=1:length(names)
                   name = char(names(i));
                   setappdata(hObject, name, getfield(appdata,name));
               end
            end
            
            if ~isempty(createfcn)
               if isa(createfcn,'function_handle')
                   createfcn(hObject, eventdata);
               else
                   eval(createfcn);
               end
            end
        end
        
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.h_zeffiro_window_main = uifigure('Visible', 'off');
            app.h_zeffiro_window_main.Name = 'ZEFFIRO Interface: Segmentation tool';
            app.h_menu_project = uimenu(app.h_zeffiro_window_main);
            app.h_menu_project.Text = 'Project';
            app.h_menu_new = uimenu(app.h_menu_project);
            app.h_menu_new.Separator = 'on';
            app.h_menu_new.Text = 'New';
            app.h_menu_open = uimenu(app.h_menu_project);
            app.h_menu_open.Text = 'Open';
            app.h_menu_save = uimenu(app.h_menu_project);
            app.h_menu_save.Text = 'Save';
            app.h_menu_save_as = uimenu(app.h_menu_project);
            app.h_menu_save_as.Text = 'Save as...';
            app.h_menu_save_figures_as = uimenu(app.h_menu_project);
            app.h_menu_save_figures_as.Text = 'Save figures as...';
            app.h_menu_print_to_file = uimenu(app.h_menu_project);
            app.h_menu_print_to_file.Text = 'Print figure to file as...';
            app.h_menu_exit = uimenu(app.h_menu_project);
            app.h_menu_exit.Text = 'Exit';
            app.h_menu_export = uimenu(app.h_zeffiro_window_main);
            app.h_menu_export.Text = 'Export';
            app.h_menu_export_volume_data = uimenu(app.h_menu_export);
            app.h_menu_export_volume_data.Text = 'Export volume data';
            app.h_menu_segmentation_data = uimenu(app.h_menu_export);
            app.h_menu_segmentation_data.Text = 'Export segmentation data';
            app.h_menu_lead_field = uimenu(app.h_menu_export);
            app.h_menu_lead_field.Text = 'Export lead field';
            app.h_menu_source_positions = uimenu(app.h_menu_export);
            app.h_menu_source_positions.Text = 'Export source positions';
            app.h_menu_source_directions = uimenu(app.h_menu_export);
            app.h_menu_source_directions.Text = 'Export source directions';
            app.h_menu_reconstruction = uimenu(app.h_menu_export);
            app.h_menu_reconstruction.Text = 'Export reconstruction';
            app.h_menu_import = uimenu(app.h_zeffiro_window_main);
            app.h_menu_import.Text = 'Import';
            app.h_menu_new_segmentation_from_folder = uimenu(app.h_menu_import);
            app.h_menu_new_segmentation_from_folder.Text = 'Import new ASCII segmentation from folder';
            app.h_menu_import_segmentation_update_from_folder = uimenu(app.h_menu_import);
            app.h_menu_import_segmentation_update_from_folder.Text = 'Import ASCII segmentation update from folder';
            app.h_menu_import_new_project_from_folder = uimenu(app.h_menu_import);
            app.h_menu_import_new_project_from_folder.Text = 'Import new ASCII project from folder';
            app.h_menu_import_project_update_from_folder = uimenu(app.h_menu_import);
            app.h_menu_import_project_update_from_folder.Text = 'Import ASCII project update from folder';
            app.h_menu_import_volume_data = uimenu(app.h_menu_import);
            app.h_menu_import_volume_data.Visible = 'off';
            app.h_menu_import_volume_data.Text = 'Import volume data';
            app.h_menu_import_measurement_data = uimenu(app.h_menu_import);
            app.h_menu_import_measurement_data.Text = 'Import measurement data';
            app.h_menu_import_noise_data = uimenu(app.h_menu_import);
            app.h_menu_import_noise_data.Text = 'Import noise data';
            app.h_menu_import_reconstruction = uimenu(app.h_menu_import);
            app.h_menu_import_reconstruction.Text = 'Import reconstruction';
            app.h_menu_import_current_pattern = uimenu(app.h_menu_import);
            app.h_menu_import_current_pattern.Text = 'Import current pattern';
            app.h_menu_edit = uimenu(app.h_zeffiro_window_main);
            app.h_menu_edit.Text = 'Edit';
            app.h_menu_reset_lead_field = uimenu(app.h_menu_edit);
            app.h_menu_reset_lead_field.Text = 'Reset lead field';
            app.h_menu_reset_volume_data = uimenu(app.h_menu_edit);
            app.h_menu_reset_volume_data.Text = 'Reset volume data';
            app.h_menu_reset_measurement_data = uimenu(app.h_menu_edit);
            app.h_menu_reset_measurement_data.Text = 'Reset inversion data ';
            app.h_menu_reset_reconstruction = uimenu(app.h_menu_edit);
            app.h_menu_reset_reconstruction.Text = 'Reset reconstruction';
            app.h_menu_merge_lead_field = uimenu(app.h_menu_edit);
            app.h_menu_merge_lead_field.Text = 'Merge lead field with...';
            app.h_menu_inverse_tools = uimenu(app.h_zeffiro_window_main);
            app.h_menu_inverse_tools.Checked = 'on';
            app.h_menu_inverse_tools.Text = 'Inverse tools';
            app.h_menu_ias_map_estimation = uimenu(app.h_menu_inverse_tools);
            app.h_menu_ias_map_estimation.Accelerator = '1';
            app.h_menu_ias_map_estimation.Text = 'IAS MAP estimation';
            app.h_menu_ias_map_estimation_roi = uimenu(app.h_menu_inverse_tools);
            app.h_menu_ias_map_estimation_roi.Accelerator = '2';
            app.h_menu_ias_map_estimation_roi.Text = 'IAS MAP estimation ROI';
            app.h_menu_ias_map_multiresolution = uimenu(app.h_menu_inverse_tools);
            app.h_menu_ias_map_multiresolution.Accelerator = '3';
            app.h_menu_ias_map_multiresolution.Text = 'IAS MAP multiresolution (RAMUS)';
            app.h_menu_forward_tools = uimenu(app.h_zeffiro_window_main);
            app.h_menu_forward_tools.Text = 'Forward tools';
            app.h_menu_butterfly_plot = uimenu(app.h_menu_forward_tools);
            app.h_menu_butterfly_plot.Text = 'Butterfly plot';
            app.h_menu_find_synthetic_source = uimenu(app.h_menu_forward_tools);
            app.h_menu_find_synthetic_source.Text = 'Find synthetic source';
            app.h_menu_generate_eit_data = uimenu(app.h_menu_forward_tools);
            app.h_menu_generate_eit_data.Text = 'Generate synthetic EIT data';
            app.h_menu_multi_tools = uimenu(app.h_zeffiro_window_main);
            app.h_menu_multi_tools.Text = 'Multi-tools';
            app.h_menu_mesh_tool = uimenu(app.h_menu_multi_tools);
            app.h_menu_mesh_tool.Accelerator = '4';
            app.h_menu_mesh_tool.Text = 'Mesh tool';
            app.h_menu_figure_tool = uimenu(app.h_menu_multi_tools);
            app.h_menu_figure_tool.Accelerator = '5';
            app.h_menu_figure_tool.Text = 'Figure tool';
            app.h_menu_parcellation_tool = uimenu(app.h_menu_multi_tools);
            app.h_menu_parcellation_tool.Accelerator = '6';
            app.h_menu_parcellation_tool.Text = 'Parcellation tool';
            app.h_menu_options = uimenu(app.h_menu_multi_tools);
            app.h_menu_options.Accelerator = '7';
            app.h_menu_options.Text = 'Options';
            app.h_menu_window = uimenu(app.h_zeffiro_window_main);
            app.h_menu_window.Text = 'Window';
            app.h_menu_close_tools = uimenu(app.h_menu_window);
            app.h_menu_close_tools.Text = 'Close tools';
            app.h_menu_close_figures = uimenu(app.h_menu_window);
            app.h_menu_close_figures.Text = 'Close figures';
            app.h_menu_help = uimenu(app.h_zeffiro_window_main);
            app.h_menu_help.Text = 'Help';
            app.h_menu_documentation = uimenu(app.h_menu_help);
            app.h_menu_documentation.Text = 'Documentation';
            app.h_menu_about = uimenu(app.h_menu_help);
            app.h_menu_about.Text = 'About';
            app.h_w_name = uilabel(app.h_zeffiro_window_main);
            app.h_w_name.Text = 'White matter';
            app.h_w_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_w_on.Text = 'On';
            app.h_pushbutton3 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton3.Text = 'Points';
            app.h_g_name = uilabel(app.h_zeffiro_window_main);
            app.h_g_name.Text = 'Grey matter';
            app.h_pushbutton4 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton4.Text = 'Triangles';
            app.h_pushbutton5 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton5.Text = 'Points';
            app.h_c_name = uilabel(app.h_zeffiro_window_main);
            app.h_c_name.Text = 'Cerebrospinal fluid';
            app.h_pushbutton6 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton6.Text = 'Triangles';
            app.h_pushbutton7 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton7.Text = 'Points';
            app.h_sk_name = uilabel(app.h_zeffiro_window_main);
            app.h_sk_name.Text = 'Skull';
            app.h_pushbutton8 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton8.Text = 'Triangles';
            app.h_pushbutton9 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton9.Text = 'Points';
            app.h_sc_name = uilabel(app.h_zeffiro_window_main);
            app.h_sc_name.Text = 'Scalp';
            app.h_pushbutton10 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton10.Text = 'Triangles';
            app.h_g_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_g_on.Text = 'On';
            app.h_c_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_c_on.Text = 'On';
            app.h_sk_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_sk_on.Text = 'On';
            app.h_sc_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_sc_on.Text = 'On';
            app.h_s_name = uilabel(app.h_zeffiro_window_main);
            app.h_s_name.Text = 'Sensors';
            app.text11 = uilabel(app.h_zeffiro_window_main);
            app.text11.Text = 'x-translation:';
            app.text14 = uilabel(app.h_zeffiro_window_main);
            app.text14.Text = 'xy-rotation:';
            app.text15 = uilabel(app.h_zeffiro_window_main);
            app.text15.Text = 'yz-rotation:';
            app.text16 = uilabel(app.h_zeffiro_window_main);
            app.text16.Text = 'zx-rotation:';
            app.text32 = uilabel(app.h_zeffiro_window_main);
            app.text32.Text = 'y-translation:';
            app.text33 = uilabel(app.h_zeffiro_window_main);
            app.text33.Text = 'z-translation:';
            app.h_edit3 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit3.Value = '0';
            app.h_edit6 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit6.Value = '0';
            app.h_edit7 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit7.Value = '0';
            app.h_edit12 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit12.Value = '0';
            app.h_edit13 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit13.Value = '0';
            app.h_edit14 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit14.Value = '0';
            app.h_edit15 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit15.Value = '1';
            app.text36 = uilabel(app.h_zeffiro_window_main);
            app.text36.Text = 'mm';
            app.text61 = uilabel(app.h_zeffiro_window_main);
            app.text61.Text = 'Scaling:';
            app.h_edit29 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit29.Enable = 'off';
            app.h_edit29.Value = '1';
            app.h_edit36 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit36.Enable = 'off';
            app.h_edit36.Value = '1';
            app.h_edit50 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit50.Enable = 'off';
            app.h_edit50.Value = '1';
            app.h_edit57 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit57.Enable = 'off';
            app.h_edit57.Value = '1';
            app.h_edit64 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit64.Enable = 'off';
            app.h_edit64.Value = '1';
            app.h_w_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_w_visible.Text = 'Visible';
            app.h_w_visible.Value = true;
            app.h_g_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_g_visible.Text = 'Visible';
            app.h_g_visible.Value = true;
            app.h_c_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_c_visible.Text = 'Visible';
            app.h_c_visible.Value = true;
            app.h_sk_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_sk_visible.Text = 'Visible';
            app.h_sk_visible.Value = true;
            app.h_sc_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_sc_visible.Text = 'Visible';
            app.h_sc_visible.Value = true;
            app.h_pushbutton1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton1.Text = 'Points';
            app.h_pushbutton2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton2.Text = 'Triangles';
            app.h_pushbutton16 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton16.Text = 'Points';
            app.h_pushbutton17 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton17.Text = 'Directions';
            app.h_s_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_s_visible.Text = 'Visible';
            app.h_s_visible.Value = true;
            app.h_edit70 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit70.Enable = 'off';
            app.h_edit70.Value = '0.14';
            app.h_edit71 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit71.Enable = 'off';
            app.h_edit71.Value = '0.33';
            app.h_edit72 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit72.Enable = 'off';
            app.h_edit72.Value = '1.79';
            app.h_edit73 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit73.Enable = 'off';
            app.h_edit73.Value = '0.0064';
            app.h_edit74 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit74.Enable = 'off';
            app.h_edit74.Value = '0.33';
            app.h_s_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_s_on.Visible = 'off';
            app.h_s_on.Text = 'On';
            app.h_s_on.Value = true;
            app.text162 = uilabel(app.h_zeffiro_window_main);
            app.text162.Text = 'deg';
            app.text163 = uilabel(app.h_zeffiro_window_main);
            app.text163.Text = 'deg';
            app.text164 = uilabel(app.h_zeffiro_window_main);
            app.text164.Text = 'deg';
            app.text168 = uilabel(app.h_zeffiro_window_main);
            app.text168.Text = 'mm';
            app.text169 = uilabel(app.h_zeffiro_window_main);
            app.text169.Text = 'mm';
            app.text185 = uilabel(app.h_zeffiro_window_main);
            app.text185.Text = 'Sigma:';
            app.text186 = uilabel(app.h_zeffiro_window_main);
            app.text186.Text = 'Scaling:';
            app.text187 = uilabel(app.h_zeffiro_window_main);
            app.text187.Text = 'Scaling:';
            app.text188 = uilabel(app.h_zeffiro_window_main);
            app.text188.Text = 'Sigma:';
            app.text189 = uilabel(app.h_zeffiro_window_main);
            app.text189.Text = 'Scaling:';
            app.text190 = uilabel(app.h_zeffiro_window_main);
            app.text190.Text = 'Sigma:';
            app.text191 = uilabel(app.h_zeffiro_window_main);
            app.text191.Text = 'Scaling:';
            app.text192 = uilabel(app.h_zeffiro_window_main);
            app.text192.Text = 'Sigma:';
            app.text193 = uilabel(app.h_zeffiro_window_main);
            app.text193.Text = 'Scaling:';
            app.text194 = uilabel(app.h_zeffiro_window_main);
            app.text194.Text = 'Sigma:';
            app.h_pushbutton101 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton101.Text = 'Points';
            app.h_d1_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d1_name.Value = 'Detail 1';
            app.h_pushbutton102 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton102.Text = 'Triangles';
            app.h_pushbutton201 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton201.Text = 'Points';
            app.h_d2_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d2_name.Value = 'Detail 2';
            app.h_pushbutton202 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton202.Text = 'Triangles';
            app.h_d1_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d1_on.Text = 'On';
            app.h_d2_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d2_on.Text = 'On';
            app.h_edit129 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit129.Enable = 'off';
            app.h_edit129.Value = '1';
            app.h_edit229 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit229.Enable = 'off';
            app.h_edit229.Value = '1';
            app.h_d1_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d1_visible.Text = 'Visible';
            app.h_d1_visible.Value = true;
            app.h_d2_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d2_visible.Text = 'Visible';
            app.h_d2_visible.Value = true;
            app.h_edit170 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit170.Enable = 'off';
            app.h_edit170.Value = '0.33';
            app.h_edit270 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit270.Enable = 'off';
            app.h_edit270.Value = '0.33';
            app.text237 = uilabel(app.h_zeffiro_window_main);
            app.text237.Text = 'Scaling:';
            app.text238 = uilabel(app.h_zeffiro_window_main);
            app.text238.Text = 'Sigma:';
            app.text239 = uilabel(app.h_zeffiro_window_main);
            app.text239.Text = 'Scaling:';
            app.text240 = uilabel(app.h_zeffiro_window_main);
            app.text240.Text = 'Sigma:';
            app.h_pushbutton301 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton301.Text = 'Points';
            app.h_d3_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d3_name.Value = 'Detail 3';
            app.h_pushbutton302 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton302.Text = 'Triangles';
            app.h_d3_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d3_on.Text = 'On';
            app.h_edit329 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit329.Enable = 'off';
            app.h_edit329.Value = '1';
            app.h_d3_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d3_visible.Text = 'Visible';
            app.h_d3_visible.Value = true;
            app.h_edit370 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit370.Enable = 'off';
            app.h_edit370.Value = '0.33';
            app.text256 = uilabel(app.h_zeffiro_window_main);
            app.text256.Text = 'Scaling:';
            app.text257 = uilabel(app.h_zeffiro_window_main);
            app.text257.Text = 'Sigma:';
            app.h_edit9001 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9001.Enable = 'off';
            app.h_edit9001.Value = '5';
            app.h_edit9002 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9002.Enable = 'off';
            app.h_edit9002.Value = '4';
            app.h_edit9003 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9003.Enable = 'off';
            app.h_edit9003.Value = '3';
            app.h_edit9004 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9004.Enable = 'off';
            app.h_edit9004.Value = '2';
            app.h_edit9005 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9005.Enable = 'off';
            app.h_edit9005.Value = '1';
            app.h_edit9006 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9006.Enable = 'off';
            app.h_edit9006.Value = '6';
            app.h_edit9007 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9007.Enable = 'off';
            app.h_edit9007.Value = '7';
            app.h_edit9008 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9008.Enable = 'off';
            app.h_edit9008.Value = '8';
            app.text260 = uilabel(app.h_zeffiro_window_main);
            app.text260.Text = 'Priority:';
            app.text261 = uilabel(app.h_zeffiro_window_main);
            app.text261.Text = 'Priority:';
            app.text262 = uilabel(app.h_zeffiro_window_main);
            app.text262.Text = 'Priority:';
            app.text263 = uilabel(app.h_zeffiro_window_main);
            app.text263.Text = 'Priority:';
            app.text264 = uilabel(app.h_zeffiro_window_main);
            app.text264.Text = 'Priority:';
            app.text265 = uilabel(app.h_zeffiro_window_main);
            app.text265.Text = 'Priority:';
            app.text266 = uilabel(app.h_zeffiro_window_main);
            app.text266.Text = 'Priority:';
            app.text267 = uilabel(app.h_zeffiro_window_main);
            app.text267.Text = 'Priority:';
            app.h_pushbutton401 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton401.Text = 'Points';
            app.h_d4_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d4_name.Value = 'Detail 4';
            app.h_pushbutton402 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_pushbutton402.Text = 'Triangles';
            app.h_d4_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d4_on.Text = 'On';
            app.h_edit429 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit429.Enable = 'off';
            app.h_edit429.Value = '1';
            app.h_d4_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d4_visible.Text = 'Visible';
            app.h_d4_visible.Value = true;
            app.h_edit470 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit470.Enable = 'off';
            app.h_edit470.Value = '0.33';
            app.text305 = uilabel(app.h_zeffiro_window_main);
            app.text305.Text = 'Scaling:';
            app.text306 = uilabel(app.h_zeffiro_window_main);
            app.text306.Text = 'Sigma:';
            app.h_edit9009 = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_edit9009.Enable = 'off';
            app.h_edit9009.Value = '9';
            app.text307 = uilabel(app.h_zeffiro_window_main);
            app.text307.Text = 'Priority:';
            app.h_w_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_w_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity', 'Inner cortex'};
            app.h_w_sources.Value = 'Inactive';
            app.h_d1_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d1_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d1_sources.Value = 'Inactive';
            app.h_d2_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d2_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d2_sources.Value = 'Inactive';
            app.h_d3_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d3_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d3_sources.Value = 'Inactive';
            app.h_d4_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d4_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d4_sources.Value = 'Inactive';
            app.h_g_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_g_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_g_sources.Value = 'Inactive';
            app.h_c_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_c_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_c_sources.Value = 'Inactive';
            app.h_sk_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_sk_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_sk_sources.Value = 'Inactive';
            app.h_sc_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_sc_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_sc_sources.Value = 'Inactive';
            app.h_w_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_w_merge.Text = 'Merge';
            app.h_w_merge.Value = true;
            app.h_g_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_g_merge.Text = 'Merge';
            app.h_g_merge.Value = true;
            app.h_c_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_c_merge.Text = 'Merge';
            app.h_c_merge.Value = true;
            app.h_sk_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_sk_merge.Text = 'Merge';
            app.h_sk_merge.Value = true;
            app.h_sc_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_sc_merge.Text = 'Merge';
            app.h_sc_merge.Value = true;
            app.h_w_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_w_invert.Text = 'Invert normal';
            app.h_g_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_g_invert.Text = 'Invert normal';
            app.h_c_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_c_invert.Text = 'Invert normal';
            app.h_sk_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_sk_invert.Text = 'Invert normal';
            app.h_sc_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_sc_invert.Text = 'Invert normal';
            app.h_d1_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d1_merge.Text = 'Merge';
            app.h_d1_merge.Value = true;
            app.h_d2_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d2_merge.Text = 'Merge';
            app.h_d2_merge.Value = true;
            app.h_d1_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d1_invert.Text = 'Invert normal';
            app.h_d2_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d2_invert.Text = 'Invert normal';
            app.h_d3_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d3_merge.Text = 'Merge';
            app.h_d3_merge.Value = true;
            app.h_d3_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d3_invert.Text = 'Invert normal';
            app.h_d4_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d4_merge.Text = 'Merge';
            app.h_d4_merge.Value = true;
            app.h_d4_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d4_invert.Text = 'Invert normal';
            app.h_d5_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d5_name.Value = 'Detail 5';
            app.h_d5_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d5_on.Text = 'On';
            app.h_d6_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d6_button_1.Text = 'Points';
            app.h_d6_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d6_name.Value = 'Detail 6';
            app.h_d6_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d6_button_2.Text = 'Triangles';
            app.h_d7_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d7_button_1.Text = 'Points';
            app.h_d7_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d7_name.Value = 'Detail 7';
            app.h_d7_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d7_button_2.Text = 'Triangles';
            app.h_d8_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d8_button_1.Text = 'Points';
            app.h_d8_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d8_name.Value = 'Detail 8';
            app.h_d8_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d8_button_2.Text = 'Triangles';
            app.h_d9_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d9_button_1.Text = 'Points';
            app.h_d9_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d9_name.Value = 'Detail 9';
            app.h_d9_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d9_button_2.Text = 'Triangles';
            app.h_d6_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d6_on.Text = 'On';
            app.h_d7_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d7_on.Text = 'On';
            app.h_d8_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d8_on.Text = 'On';
            app.h_d9_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d9_on.Text = 'On';
            app.text324 = uilabel(app.h_zeffiro_window_main);
            app.text324.Text = 'Scaling:';
            app.h_d5_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d5_scaling.Enable = 'off';
            app.h_d5_scaling.Value = '1';
            app.h_d6_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d6_scaling.Enable = 'off';
            app.h_d6_scaling.Value = '1';
            app.h_d7_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d7_scaling.Enable = 'off';
            app.h_d7_scaling.Value = '1';
            app.h_d8_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d8_scaling.Enable = 'off';
            app.h_d8_scaling.Value = '1';
            app.h_d9_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d9_scaling.Enable = 'off';
            app.h_d9_scaling.Value = '1';
            app.h_d5_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d5_visible.Text = 'Visible';
            app.h_d5_visible.Value = true;
            app.h_d6_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d6_visible.Text = 'Visible';
            app.h_d6_visible.Value = true;
            app.h_d7_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d7_visible.Text = 'Visible';
            app.h_d7_visible.Value = true;
            app.h_d8_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d8_visible.Text = 'Visible';
            app.h_d8_visible.Value = true;
            app.h_d9_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d9_visible.Text = 'Visible';
            app.h_d9_visible.Value = true;
            app.h_d5_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d5_button_1.Text = 'Points';
            app.h_d5_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d5_button_2.Text = 'Triangles';
            app.h_d5_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d5_sigma.Enable = 'off';
            app.h_d5_sigma.Value = '0.33';
            app.h_d6_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d6_sigma.Enable = 'off';
            app.h_d6_sigma.Value = '0.33';
            app.h_d7_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d7_sigma.Enable = 'off';
            app.h_d7_sigma.Value = '0.33';
            app.h_d8_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d8_sigma.Enable = 'off';
            app.h_d8_sigma.Value = '0.33';
            app.h_d9_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d9_sigma.Enable = 'off';
            app.h_d9_sigma.Value = '0.33';
            app.text325 = uilabel(app.h_zeffiro_window_main);
            app.text325.Text = 'Sigma:';
            app.text326 = uilabel(app.h_zeffiro_window_main);
            app.text326.Text = 'Scaling:';
            app.text327 = uilabel(app.h_zeffiro_window_main);
            app.text327.Text = 'Sigma:';
            app.text328 = uilabel(app.h_zeffiro_window_main);
            app.text328.Text = 'Scaling:';
            app.text329 = uilabel(app.h_zeffiro_window_main);
            app.text329.Text = 'Sigma:';
            app.text330 = uilabel(app.h_zeffiro_window_main);
            app.text330.Text = 'Scaling:';
            app.text331 = uilabel(app.h_zeffiro_window_main);
            app.text331.Text = 'Sigma:';
            app.text332 = uilabel(app.h_zeffiro_window_main);
            app.text332.Text = 'Scaling:';
            app.text333 = uilabel(app.h_zeffiro_window_main);
            app.text333.Text = 'Sigma:';
            app.h_d10_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d10_button_1.Text = 'Points';
            app.h_d10_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d10_name.Value = 'Detail 10';
            app.h_d10_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d10_button_2.Text = 'Triangles';
            app.h_d11_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d11_button_1.Text = 'Points';
            app.h_d11_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d11_name.Value = 'Detail 11';
            app.h_d11_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d11_button_2.Text = 'Triangles';
            app.h_d10_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d10_on.Text = 'On';
            app.h_d11_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d11_on.Text = 'On';
            app.h_d10_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d10_scaling.Enable = 'off';
            app.h_d10_scaling.Value = '1';
            app.h_d11_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d11_scaling.Enable = 'off';
            app.h_d11_scaling.Value = '1';
            app.h_d10_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d10_visible.Text = 'Visible';
            app.h_d10_visible.Value = true;
            app.h_d11_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d11_visible.Text = 'Visible';
            app.h_d11_visible.Value = true;
            app.h_d10_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d10_sigma.Enable = 'off';
            app.h_d10_sigma.Value = '0.33';
            app.h_d11_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d11_sigma.Enable = 'off';
            app.h_d11_sigma.Value = '0.33';
            app.text336 = uilabel(app.h_zeffiro_window_main);
            app.text336.Text = 'Scaling:';
            app.text337 = uilabel(app.h_zeffiro_window_main);
            app.text337.Text = 'Sigma:';
            app.text338 = uilabel(app.h_zeffiro_window_main);
            app.text338.Text = 'Scaling:';
            app.text339 = uilabel(app.h_zeffiro_window_main);
            app.text339.Text = 'Sigma:';
            app.h_d12_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d12_button_1.Text = 'Points';
            app.h_d12_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d12_name.Value = 'Detail 12';
            app.h_d12_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d12_button_2.Text = 'Triangles';
            app.h_d12_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d12_on.Text = 'On';
            app.h_d12_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d12_scaling.Enable = 'off';
            app.h_d12_scaling.Value = '1';
            app.h_d12_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d12_visible.Text = 'Visible';
            app.h_d12_visible.Value = true;
            app.h_d12_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d12_sigma.Enable = 'off';
            app.h_d12_sigma.Value = '0.33';
            app.text341 = uilabel(app.h_zeffiro_window_main);
            app.text341.Text = 'Scaling:';
            app.text342 = uilabel(app.h_zeffiro_window_main);
            app.text342.Text = 'Sigma:';
            app.h_d5_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d5_priority.Enable = 'off';
            app.h_d5_priority.Value = '10';
            app.h_d6_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d6_priority.Enable = 'off';
            app.h_d6_priority.Value = '11';
            app.h_d7_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d7_priority.Enable = 'off';
            app.h_d7_priority.Value = '12';
            app.h_d8_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d8_priority.Enable = 'off';
            app.h_d8_priority.Value = '13';
            app.h_d9_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d9_priority.Enable = 'off';
            app.h_d9_priority.Value = '14';
            app.h_d10_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d10_priority.Enable = 'off';
            app.h_d10_priority.Value = '15';
            app.h_d11_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d11_priority.Enable = 'off';
            app.h_d11_priority.Value = '16';
            app.h_d12_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d12_priority.Enable = 'off';
            app.h_d12_priority.Value = '17';
            app.text343 = uilabel(app.h_zeffiro_window_main);
            app.text343.Text = 'Priority:';
            app.text344 = uilabel(app.h_zeffiro_window_main);
            app.text344.Text = 'Priority:';
            app.text345 = uilabel(app.h_zeffiro_window_main);
            app.text345.Text = 'Priority:';
            app.text346 = uilabel(app.h_zeffiro_window_main);
            app.text346.Text = 'Priority:';
            app.text347 = uilabel(app.h_zeffiro_window_main);
            app.text347.Text = 'Priority:';
            app.text348 = uilabel(app.h_zeffiro_window_main);
            app.text348.Text = 'Priority:';
            app.text349 = uilabel(app.h_zeffiro_window_main);
            app.text349.Text = 'Priority:';
            app.text350 = uilabel(app.h_zeffiro_window_main);
            app.text350.Text = 'Priority:';
            app.h_d13_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d13_button_1.Text = 'Points';
            app.h_d13_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d13_name.Value = 'Detail 13';
            app.h_d13_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d13_button_2.Text = 'Triangles';
            app.h_d13_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d13_on.Text = 'On';
            app.h_d13_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d13_scaling.Enable = 'off';
            app.h_d13_scaling.Value = '1';
            app.h_d13_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d13_visible.Text = 'Visible';
            app.h_d13_visible.Value = true;
            app.h_d13_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d13_sigma.Enable = 'off';
            app.h_d13_sigma.Value = '0.33';
            app.text352 = uilabel(app.h_zeffiro_window_main);
            app.text352.Text = 'Scaling:';
            app.text353 = uilabel(app.h_zeffiro_window_main);
            app.text353.Text = 'Sigma:';
            app.h_d13_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d13_priority.Enable = 'off';
            app.h_d13_priority.Value = '18';
            app.text354 = uilabel(app.h_zeffiro_window_main);
            app.text354.Text = 'Priority:';
            app.h_d5_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d5_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d5_sources.Value = 'Inactive';
            app.h_d10_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d10_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d10_sources.Value = 'Inactive';
            app.h_d11_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d11_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d11_sources.Value = 'Inactive';
            app.h_d12_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d12_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d12_sources.Value = 'Inactive';
            app.h_d13_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d13_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d13_sources.Value = 'Inactive';
            app.h_d6_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d6_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d6_sources.Value = 'Inactive';
            app.h_d7_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d7_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d7_sources.Value = 'Inactive';
            app.h_d8_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d8_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d8_sources.Value = 'Inactive';
            app.h_d9_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d9_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d9_sources.Value = 'Inactive';
            app.h_d5_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d5_merge.Text = 'Merge';
            app.h_d5_merge.Value = true;
            app.h_d6_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d6_merge.Text = 'Merge';
            app.h_d6_merge.Value = true;
            app.h_d7_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d7_merge.Text = 'Merge';
            app.h_d7_merge.Value = true;
            app.h_d8_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d8_merge.Text = 'Merge';
            app.h_d8_merge.Value = true;
            app.h_d9_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d9_merge.Text = 'Merge';
            app.h_d9_merge.Value = true;
            app.h_d5_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d5_invert.Text = 'Invert normal';
            app.h_d6_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d6_invert.Text = 'Invert normal';
            app.h_d7_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d7_invert.Text = 'Invert normal';
            app.h_d8_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d8_invert.Text = 'Invert normal';
            app.h_d9_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d9_invert.Text = 'Invert normal';
            app.h_d10_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d10_merge.Text = 'Merge';
            app.h_d10_merge.Value = true;
            app.h_d11_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d11_merge.Text = 'Merge';
            app.h_d11_merge.Value = true;
            app.h_d10_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d10_invert.Text = 'Invert normal';
            app.h_d11_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d11_invert.Text = 'Invert normal';
            app.h_d12_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d12_merge.Text = 'Merge';
            app.h_d12_merge.Value = true;
            app.h_d12_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d12_invert.Text = 'Invert normal';
            app.h_d13_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d13_merge.Text = 'Merge';
            app.h_d13_merge.Value = true;
            app.h_d13_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d13_invert.Text = 'Invert normal';
            app.text355 = uilabel(app.h_zeffiro_window_main);
            app.text355.Text = {'Forward and inverse interface for '; 'electromagnetic brain imaging'};
            app.text356 = uilabel(app.h_zeffiro_window_main);
            app.text356.Text = 'xy-view:';
            app.text357 = uilabel(app.h_zeffiro_window_main);
            app.text357.Text = 'yz-view:';
            app.text358 = uilabel(app.h_zeffiro_window_main);
            app.text358.Text = 'zx-view:';
            app.text359 = uilabel(app.h_zeffiro_window_main);
            app.text359.Text = '0,90';
            app.text360 = uilabel(app.h_zeffiro_window_main);
            app.text360.Text = '90,0';
            app.text361 = uilabel(app.h_zeffiro_window_main);
            app.text361.Text = '180,0';
            app.h_d14_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d14_name.Value = 'Detail 14';
            app.h_d14_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d14_on.Text = 'On';
            app.h_d15_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d15_button_1.Text = 'Points';
            app.h_d15_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d15_name.Value = 'Detail 15';
            app.h_d15_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d15_button_2.Text = 'Triangles';
            app.h_d16_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d16_button_1.Text = 'Points';
            app.h_d16_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d16_name.Value = 'Detail 16';
            app.h_d16_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d16_button_2.Text = 'Triangles';
            app.h_d17_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d17_button_1.Text = 'Points';
            app.h_d17_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d17_name.Value = 'Detail 17';
            app.h_d17_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d17_button_2.Text = 'Triangles';
            app.h_d18_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d18_button_1.Text = 'Points';
            app.h_d18_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d18_name.Value = 'Detail 18';
            app.h_d18_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d18_button_2.Text = 'Triangles';
            app.h_d15_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d15_on.Text = 'On';
            app.h_d16_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d16_on.Text = 'On';
            app.h_d17_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d17_on.Text = 'On';
            app.h_d18_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d18_on.Text = 'On';
            app.text389 = uilabel(app.h_zeffiro_window_main);
            app.text389.Text = 'Scaling:';
            app.h_d14_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d14_scaling.Enable = 'off';
            app.h_d14_scaling.Value = '1';
            app.h_d15_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d15_scaling.Enable = 'off';
            app.h_d15_scaling.Value = '1';
            app.h_d16_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d16_scaling.Enable = 'off';
            app.h_d16_scaling.Value = '1';
            app.h_d17_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d17_scaling.Enable = 'off';
            app.h_d17_scaling.Value = '1';
            app.h_d18_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d18_scaling.Enable = 'off';
            app.h_d18_scaling.Value = '1';
            app.h_d14_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d14_visible.Text = 'Visible';
            app.h_d14_visible.Value = true;
            app.h_d15_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d15_visible.Text = 'Visible';
            app.h_d15_visible.Value = true;
            app.h_d16_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d16_visible.Text = 'Visible';
            app.h_d16_visible.Value = true;
            app.h_d17_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d17_visible.Text = 'Visible';
            app.h_d17_visible.Value = true;
            app.h_d18_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d18_visible.Text = 'Visible';
            app.h_d18_visible.Value = true;
            app.h_d14_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d14_button_1.Text = 'Points';
            app.h_d14_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d14_button_2.Text = 'Triangles';
            app.h_d14_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d14_sigma.Enable = 'off';
            app.h_d14_sigma.Value = '0.33';
            app.h_d15_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d15_sigma.Enable = 'off';
            app.h_d15_sigma.Value = '0.33';
            app.h_d16_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d16_sigma.Enable = 'off';
            app.h_d16_sigma.Value = '0.33';
            app.h_d17_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d17_sigma.Enable = 'off';
            app.h_d17_sigma.Value = '0.33';
            app.h_d18_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d18_sigma.Enable = 'off';
            app.h_d18_sigma.Value = '0.33';
            app.text390 = uilabel(app.h_zeffiro_window_main);
            app.text390.Text = 'Sigma:';
            app.text391 = uilabel(app.h_zeffiro_window_main);
            app.text391.Text = 'Scaling:';
            app.text392 = uilabel(app.h_zeffiro_window_main);
            app.text392.Text = 'Sigma:';
            app.text393 = uilabel(app.h_zeffiro_window_main);
            app.text393.Text = 'Scaling:';
            app.text394 = uilabel(app.h_zeffiro_window_main);
            app.text394.Text = 'Sigma:';
            app.text395 = uilabel(app.h_zeffiro_window_main);
            app.text395.Text = 'Scaling:';
            app.text396 = uilabel(app.h_zeffiro_window_main);
            app.text396.Text = 'Sigma:';
            app.text397 = uilabel(app.h_zeffiro_window_main);
            app.text397.Text = 'Scaling:';
            app.text398 = uilabel(app.h_zeffiro_window_main);
            app.text398.Text = 'Sigma:';
            app.h_d19_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d19_button_1.Text = 'Points';
            app.h_d19_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d19_name.Value = 'Detail 19';
            app.h_d19_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d19_button_2.Text = 'Triangles';
            app.h_d20_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d20_button_1.Text = 'Points';
            app.h_d20_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d20_name.Value = 'Detail 20';
            app.h_d20_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d20_button_2.Text = 'Triangles';
            app.h_d19_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d19_on.Text = 'On';
            app.h_d20_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d20_on.Text = 'On';
            app.h_d19_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d19_scaling.Enable = 'off';
            app.h_d19_scaling.Value = '1';
            app.h_d20_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d20_scaling.Enable = 'off';
            app.h_d20_scaling.Value = '1';
            app.h_d19_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d19_visible.Text = 'Visible';
            app.h_d19_visible.Value = true;
            app.h_d20_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d20_visible.Text = 'Visible';
            app.h_d20_visible.Value = true;
            app.h_d19_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d19_sigma.Enable = 'off';
            app.h_d19_sigma.Value = '0.33';
            app.h_d20_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d20_sigma.Enable = 'off';
            app.h_d20_sigma.Value = '0.33';
            app.text399 = uilabel(app.h_zeffiro_window_main);
            app.text399.Text = 'Scaling:';
            app.text400 = uilabel(app.h_zeffiro_window_main);
            app.text400.Text = 'Sigma:';
            app.text401 = uilabel(app.h_zeffiro_window_main);
            app.text401.Text = 'Scaling:';
            app.text402 = uilabel(app.h_zeffiro_window_main);
            app.text402.Text = 'Sigma:';
            app.h_d21_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d21_button_1.Text = 'Points';
            app.h_d21_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d21_name.Value = 'Detail 21';
            app.h_d21_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d21_button_2.Text = 'Triangles';
            app.h_d21_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d21_on.Text = 'On';
            app.h_d21_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d21_scaling.Enable = 'off';
            app.h_d21_scaling.Value = '1';
            app.h_d21_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d21_visible.Text = 'Visible';
            app.h_d21_visible.Value = true;
            app.h_d21_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d21_sigma.Enable = 'off';
            app.h_d21_sigma.Value = '0.33';
            app.text403 = uilabel(app.h_zeffiro_window_main);
            app.text403.Text = 'Scaling:';
            app.text404 = uilabel(app.h_zeffiro_window_main);
            app.text404.Text = 'Sigma:';
            app.h_d14_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d14_priority.Enable = 'off';
            app.h_d14_priority.Value = '19';
            app.h_d15_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d15_priority.Enable = 'off';
            app.h_d15_priority.Value = '20';
            app.h_d16_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d16_priority.Enable = 'off';
            app.h_d16_priority.Value = '21';
            app.h_d17_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d17_priority.Enable = 'off';
            app.h_d17_priority.Value = '22';
            app.h_d18_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d18_priority.Enable = 'off';
            app.h_d18_priority.Value = '23';
            app.h_d19_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d19_priority.Enable = 'off';
            app.h_d19_priority.Value = '24';
            app.h_d20_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d20_priority.Enable = 'off';
            app.h_d20_priority.Value = '25';
            app.h_d21_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d21_priority.Enable = 'off';
            app.h_d21_priority.Value = '26';
            app.text405 = uilabel(app.h_zeffiro_window_main);
            app.text405.Text = 'Priority:';
            app.text406 = uilabel(app.h_zeffiro_window_main);
            app.text406.Text = 'Priority:';
            app.text407 = uilabel(app.h_zeffiro_window_main);
            app.text407.Text = 'Priority:';
            app.text408 = uilabel(app.h_zeffiro_window_main);
            app.text408.Text = 'Priority:';
            app.text409 = uilabel(app.h_zeffiro_window_main);
            app.text409.Text = 'Priority:';
            app.text410 = uilabel(app.h_zeffiro_window_main);
            app.text410.Text = 'Priority:';
            app.text411 = uilabel(app.h_zeffiro_window_main);
            app.text411.Text = 'Priority:';
            app.text412 = uilabel(app.h_zeffiro_window_main);
            app.text412.Text = 'Priority:';
            app.h_d22_button_1 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d22_button_1.Text = 'Points';
            app.h_d22_name = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d22_name.Value = 'Detail 22';
            app.h_d22_button_2 = uibutton(app.h_zeffiro_window_main, 'push');
            app.h_d22_button_2.Text = 'Triangles';
            app.h_d22_on = uicheckbox(app.h_zeffiro_window_main);
            app.h_d22_on.Text = 'On';
            app.h_d22_scaling = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d22_scaling.Enable = 'off';
            app.h_d22_scaling.Value = '1';
            app.h_d22_visible = uicheckbox(app.h_zeffiro_window_main);
            app.h_d22_visible.Text = 'Visible';
            app.h_d22_visible.Value = true;
            app.h_d22_sigma = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d22_sigma.Enable = 'off';
            app.h_d22_sigma.Value = '0.33';
            app.text413 = uilabel(app.h_zeffiro_window_main);
            app.text413.Text = 'Scaling:';
            app.text414 = uilabel(app.h_zeffiro_window_main);
            app.text414.Text = 'Sigma:';
            app.h_d22_priority = uieditfield(app.h_zeffiro_window_main, 'text');
            app.h_d22_priority.Enable = 'off';
            app.h_d22_priority.Value = '27';
            app.text415 = uilabel(app.h_zeffiro_window_main);
            app.text415.Text = 'Priority:';
            app.h_d14_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d14_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d14_sources.Value = 'Inactive';
            app.h_d19_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d19_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d19_sources.Value = 'Inactive';
            app.h_d20_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d20_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d20_sources.Value = 'Inactive';
            app.h_d21_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d21_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d21_sources.Value = 'Inactive';
            app.h_d22_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d22_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d22_sources.Value = 'Inactive';
            app.h_d15_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d15_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d15_sources.Value = 'Inactive';
            app.h_d16_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d16_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d16_sources.Value = 'Inactive';
            app.h_d17_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d17_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d17_sources.Value = 'Inactive';
            app.h_d18_sources = uidropdown(app.h_zeffiro_window_main);
            app.h_d18_sources.Items = {'Inactive', 'Constrained activity', 'Unconstrained activity'};
            app.h_d18_sources.Value = 'Inactive';
            app.h_d14_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d14_merge.Text = 'Merge';
            app.h_d14_merge.Value = true;
            app.h_d15_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d15_merge.Text = 'Merge';
            app.h_d15_merge.Value = true;
            app.h_d16_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d16_merge.Text = 'Merge';
            app.h_d16_merge.Value = true;
            app.h_d17_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d17_merge.Text = 'Merge';
            app.h_d17_merge.Value = true;
            app.h_d18_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d18_merge.Text = 'Merge';
            app.h_d18_merge.Value = true;
            app.h_d14_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d14_invert.Text = 'Invert normal';
            app.h_d15_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d15_invert.Text = 'Invert normal';
            app.h_d16_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d16_invert.Text = 'Invert normal';
            app.h_d17_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d17_invert.Text = 'Invert normal';
            app.h_d18_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d18_invert.Text = 'Invert normal';
            app.h_d19_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d19_merge.Text = 'Merge';
            app.h_d19_merge.Value = true;
            app.h_d20_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d20_merge.Text = 'Merge';
            app.h_d20_merge.Value = true;
            app.h_d19_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d19_invert.Text = 'Invert normal';
            app.h_d20_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d20_invert.Text = 'Invert normal';
            app.h_d21_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d21_merge.Text = 'Merge';
            app.h_d21_merge.Value = true;
            app.h_d21_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d21_invert.Text = 'Invert normal';
            app.h_d22_merge = uicheckbox(app.h_zeffiro_window_main);
            app.h_d22_merge.Text = 'Merge';
            app.h_d22_merge.Value = true;
            app.h_d22_invert = uicheckbox(app.h_zeffiro_window_main);
            app.h_d22_invert.Text = 'Invert normal';
            app.text416 = uilabel(app.h_zeffiro_window_main);
            app.text416.Text = 'Project file:';
            app.text417 = uilabel(app.h_zeffiro_window_main);
            app.text417.Text = 'Project folder:';
            app.h_save_file = uilabel(app.h_zeffiro_window_main);
            app.h_save_file.Text = 'Project file name';
            app.h_save_file_path = uilabel(app.h_zeffiro_window_main);
            app.h_save_file_path.Text = 'Project file folder';
            app.text420 = uilabel(app.h_zeffiro_window_main);
            app.text420.Text = 'Size (MB):';
            app.h_project_size = uilabel(app.h_zeffiro_window_main);
            app.h_project_size.Text = 'Project size';
            app.Image = uiimage(app.h_zeffiro_window_main);
            app.Image.ImageSource = 'zeffiro_logo.jpg';
            % Show the figure after all components are created
            app.h_zeffiro_window_main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zeffiro_interface_segmentation_tool_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.h_zeffiro_window_main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.h_zeffiro_window_main)
        end
    end
end