classdef find_synthetic_source_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        h_find_synth_source       matlab.ui.Figure
        h_source_parameters       matlab.ui.control.Table
        h_add_source              matlab.ui.control.Button
        h_remove_source           matlab.ui.control.Button
        h_generate_time_sequence  matlab.ui.control.Button
        h_create_synth_data       matlab.ui.control.Button
        h_plot_sources            matlab.ui.control.Button
        SingletimepointsLabel     matlab.ui.control.Label
        h_time_val                matlab.ui.control.EditField
        h_source_list             matlab.ui.control.Table
        h_plot_switch             matlab.ui.control.Switch
        h_plot_intensity          matlab.ui.control.Button
        h_plot_time_sequence      matlab.ui.control.Button
        BackgroundNoisedBLabel    matlab.ui.control.Label
        h_bg_noise                matlab.ui.control.EditField
    end

    
    properties (Access = public)
        selected_source % Description
        intensity_direction
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Cell selection callback: h_source_list
        function h_source_listCellSelection(app, event)
            app.selected_source = event.Indices(:,1);          
            evalin('base','zef.find_synth_source.h_source_parameters.Data=zef.synth_source_data{zef.find_synth_source.selected_source(end)}.parameters;')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.h_find_synth_source = uifigure('Visible', 'off');
            app.h_find_synth_source.Name = 'ZEFFIRO Interface: Find Synthetic Source';
            app.h_source_parameters = uitable(app.h_find_synth_source);
            app.h_source_parameters.ColumnName = {'Parameters'; 'Values'};
            app.h_source_parameters.RowName = {};
            app.h_source_parameters.ColumnEditable = true;
            app.h_add_source = uibutton(app.h_find_synth_source, 'push');
            app.h_add_source.Text = 'Add new synthetic source';
            app.h_remove_source = uibutton(app.h_find_synth_source, 'push');
            app.h_remove_source.Text = 'Remove synthetic source';
            app.h_generate_time_sequence = uibutton(app.h_find_synth_source, 'push');
            app.h_generate_time_sequence.Text = 'Generate time sequence';
            app.h_create_synth_data = uibutton(app.h_find_synth_source, 'push');
            app.h_create_synth_data.Text = 'Create synthetic data';
            app.h_plot_sources = uibutton(app.h_find_synth_source, 'push');
            app.h_plot_sources.Text = 'Plot source(s)';
            app.SingletimepointsLabel = uilabel(app.h_find_synth_source);
            app.SingletimepointsLabel.Text = 'Single time point (s):';
            app.h_time_val = uieditfield(app.h_find_synth_source, 'text');
            app.h_source_list = uitable(app.h_find_synth_source);
            app.h_source_list.ColumnName = {'Sources'};
            app.h_source_list.RowName = {};
            app.h_source_list.ColumnEditable = true;
            app.h_source_list.CellSelectionCallback = createCallbackFcn(app, @h_source_listCellSelection, true);
            app.h_plot_switch = uiswitch(app.h_find_synth_source, 'slider');
            app.h_plot_switch.Items = {'All sources', 'Selected sources'};
            app.h_plot_switch.ItemsData = {'0', '1'};
            app.h_plot_switch.Value = '0';
            app.h_plot_intensity = uibutton(app.h_find_synth_source, 'push');
            app.h_plot_intensity.Text = 'Plot intensities';
            app.h_plot_time_sequence = uibutton(app.h_find_synth_source, 'push');
            app.h_plot_time_sequence.Text = 'Plot time sequence';
            app.BackgroundNoisedBLabel = uilabel(app.h_find_synth_source);
            app.BackgroundNoisedBLabel.Text = 'Background Noise (dB):';
            app.h_bg_noise = uieditfield(app.h_find_synth_source, 'text');
            % Show the figure after all components are created
            app.h_find_synth_source.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = find_synthetic_source_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.h_find_synth_source)
            else

                % Focus the running singleton app
                figure(runningApp.h_find_synth_source)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.h_find_synth_source)
        end
    end
end