classdef zeffiro_interface_lf_bank_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        h_lf_bank_tool                 matlab.ui.Figure
        h_add_lf_item                  matlab.ui.control.Button
        h_lf_tag                       matlab.ui.control.EditField
        text3                          matlab.ui.control.Label
        h_merge_lead_fields            matlab.ui.control.Button
        h_delete_selected              matlab.ui.control.Button
        h_lf_normalization             matlab.ui.control.DropDown
        h_lf_item_list                 matlab.ui.control.ListBox
        h_lf_bank_compute_lead_fields  matlab.ui.control.Button
        h_lf_bank_update_measurements  matlab.ui.control.Button
        h_lf_bank_make_all             matlab.ui.control.Button
        h_lf_bank_scaling_factor       matlab.ui.control.EditField
        text3_2                        matlab.ui.control.Label
        h_lf_bank_update_noise_data    matlab.ui.control.Button
    end

    
    methods (Access = private)
        function local_CreateFcn(app, hObject, eventdata, createfcn, appdata)
            
            if ~isempty(appdata)
               names = fieldnames(appdata);
               for i=1:length(names)
                   name = char(names(i));
                   setappdata(hObject, name, getfield(appdata,name));
               end
            end
            
            if ~isempty(createfcn)
               if isa(createfcn,'function_handle')
                   createfcn(hObject, eventdata);
               else
                   eval(createfcn);
               end
            end
        end
        
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.h_lf_bank_tool = uifigure('Visible', 'off');
            app.h_lf_bank_tool.Name = 'zeffiro_interface_lf_bank_tool';
            app.h_add_lf_item = uibutton(app.h_lf_bank_tool, 'push');
            app.h_add_lf_item.Text = 'Add item';
            app.h_lf_tag = uieditfield(app.h_lf_bank_tool, 'text');
            app.h_lf_tag.Value = 'EEG';
            app.text3 = uilabel(app.h_lf_bank_tool);
            app.text3.Text = 'Tag:';
            app.h_merge_lead_fields = uibutton(app.h_lf_bank_tool, 'push');
            app.h_merge_lead_fields.Text = 'Merge lead fields';
            app.h_delete_selected = uibutton(app.h_lf_bank_tool, 'push');
            app.h_delete_selected.Text = 'Delete items';
            app.h_lf_normalization = uidropdown(app.h_lf_bank_tool);
            app.h_lf_normalization.Items = {'No normalization', 'Frobenius norm'};
            app.h_lf_normalization.Value = 'No normalization';
            app.h_lf_item_list = uilistbox(app.h_lf_bank_tool);
            app.h_lf_bank_compute_lead_fields = uibutton(app.h_lf_bank_tool, 'push');
            app.h_lf_bank_compute_lead_fields.Text = 'Compute lead fields';
            app.h_lf_bank_update_measurements = uibutton(app.h_lf_bank_tool, 'push');
            app.h_lf_bank_update_measurements.Text = 'Update measurements';
            app.h_lf_bank_make_all = uibutton(app.h_lf_bank_tool, 'push');
            app.h_lf_bank_make_all.Text = 'Make all';
            app.h_lf_bank_scaling_factor = uieditfield(app.h_lf_bank_tool, 'text');
            app.h_lf_bank_scaling_factor.Value = '1';
            app.text3_2 = uilabel(app.h_lf_bank_tool);
            app.text3_2.Text = 'Scaling factor:';
            app.h_lf_bank_update_noise_data = uibutton(app.h_lf_bank_tool, 'push');
            app.h_lf_bank_update_noise_data.Text = 'Update noise data';
            % Show the figure after all components are created
            app.h_lf_bank_tool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zeffiro_interface_lf_bank_tool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.h_lf_bank_tool)
            else

                % Focus the running singleton app
                figure(runningApp.h_lf_bank_tool)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.h_lf_bank_tool)
        end
    end
end